classdef MethodstoCalculatePi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MethodstoCalculatePiUIFigure    matlab.ui.Figure
        CalculationMethodDropDown       matlab.ui.control.DropDown
        CalculationMethodDropDownLabel  matlab.ui.control.Label
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout10                    matlab.ui.container.GridLayout
        Imagealgo                       matlab.ui.control.Image
        GridLayout11                    matlab.ui.container.GridLayout
        TextAreacode                    matlab.ui.control.TextArea
        TextArea_2Label                 matlab.ui.control.Label
        MATLABCodeLabel                 matlab.ui.control.Label
        GridLayout7                     matlab.ui.container.GridLayout
        GridLayout9                     matlab.ui.container.GridLayout
        TextArea                        matlab.ui.control.TextArea
        GridLayout8                     matlab.ui.container.GridLayout
        UIAxesP                         matlab.ui.control.UIAxes
        UIAxesH                         matlab.ui.control.UIAxes
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout4                     matlab.ui.container.GridLayout
        GridLayout6                     matlab.ui.container.GridLayout
        RUNButton                       matlab.ui.control.Button
        GridLayout5                     matlab.ui.container.GridLayout
        TempsEditField                  matlab.ui.control.EditField
        TempsdexcutionensecLabel        matlab.ui.control.Label
        GridLayout3                     matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        NumberofDecimalPlacesEditField  matlab.ui.control.NumericEditField
        NumberofDecimalPlacesEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Ndecimales % nombre de décimales de Pi
    end
    
    methods (Access = private)
        
        function P=archimede_pi(~,d)
            digits(d);
            a = vpa(2*sqrt(2),d);
            b = vpa(4,d);
            c= (a+b)/2;

            for k=3:d
                b = b * a / c ;
                a = sqrt (b * a ) ;
                c = ( a + b ) / 2;
            end
            P=(2*a+b)/3;
        end

        function P = agm_pi(~,d)
            digits(d)
            a = vpa(1,d);
            b = 1/sqrt(vpa(2,d));
            s = 1/vpa(4,d);
            p = 1;
            n = ceil(log2(d));
            for k = 1:n
                c = (a+b)/2;
                b = sqrt(a*b);
                s = s - p*(c-a)^2;
                p = 2*p;
                a = c;
            end
            P = a^2/s;
        end

        function P = chud_pi(~,d)
            k = sym(0);
            s = sym(0);
            sig = sym(1);
            n = ceil(d/14);
            for j = 1:n
                s = s + sig * prod(3*k+1:6*k)/prod(1:k)^3 * ...
                    (13591409+545140134*k) / 640320^(3*k+3/2);
                k = k+1;
                sig = -sig;
            end
            S = 1/(12*s);
            P = vpa(S,d);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            
            app.RUNButton.Text="Calculating...";
            app.RUNButton.Enable=false;
            drawnow;  
            cla(app.UIAxesP,'reset');
            app.TempsEditField.Enable=false;
            app.Ndecimales=app.NumberofDecimalPlacesEditField.Value+1;   
            switch app.CalculationMethodDropDown.Value
                case 'Archimedes'
                    tic
                    pn=archimede_pi(app,app.Ndecimales); 
                    app.TempsEditField.Value= num2str(toc);
                    app.TempsEditField.Enable=true;
                 
                case 'Brent-Salamin'
                    tic
                    pn=agm_pi(app,app.Ndecimales); 
                    app.TempsEditField.Value= num2str(toc);
                    app.TempsEditField.Enable=true;
                  
                case 'Chudnovsky'
                    tic
                    pn=chud_pi(app,app.Ndecimales);
                    app.TempsEditField.Value= num2str(toc);
                   app.TempsEditField.Enable=true;
                   
                otherwise
                    disp('error');
            end

             app.RUNButton.Text="RUN"; 
             app.RUNButton.Enable=true;
             app.TextArea.Value=char(pn);
             p = char(pn);
             pn=[];
             p(2) = [];  % Decimal point
             p = p - '0';
             histogram(app.UIAxesH,p);

             app.UIAxesH.XLabel.String = 'Digit'; % Label for the x-axis
             app.UIAxesH.XLim = [-0.5, 9.5];          % Set the limits of the x-axis
             app.UIAxesH.XTick = 0:9;     % Set specific tick marks on the x-axis
             app.UIAxesH.XTickLabel = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9'}; % Custom labels
             app.UIAxesH.YLabel.String = 'Frequency';
  
             % Assign each digit an angular coordinate based on its value 0:9
                nDecimals = numel(p);
                theta = ((0:36:324)+(0:36/nDecimals:36)')*pi/180;
                ang = theta(sub2ind(size(theta),1:nDecimals,p+1));
                % Compute the length of each line segment; used to set color
                [x,y] = pol2cart(ang,1);
                [~,~,d] = uniquetol(hypot(diff(x),diff(y)));
                alpha = min(0.85,max(0.006, 1000/nDecimals));
                % Plot line segments using the edge property of a single Patch object
               % fig = figure(Color='k');
                %app.UIAxesP.Position(3:4) = 600;
                set(app.UIAxesP,'DataAspectRatio',[1 1 1]);
                pa = patch(app.UIAxesP,XData=x,YData=y,FaceColor='none',EdgeAlpha=alpha);
                set(pa,'FaceVertexCData', [d;nan], 'EdgeColor','Flat')
                
        end

        % Value changed function: CalculationMethodDropDown
        function CalculationMethodDropDownValueChanged(app, event)
            
             switch app.CalculationMethodDropDown.Value
                case 'Archimedes'
                    app.TextAreacode.Value=[{'digits(d);'                 }
    {'a = vpa(2*sqrt(2),d);'      }
    {'b = vpa(4,d);'              }
    {'c= (a+b)/2;'                }
    {'for k=3:d'                  }
    {'        b = b * a / c ;'    }
    {'        a = sqrt (b * a ) ;'}
    {'        c = ( a + b ) / 2;' }
    {'end'                        }
    {'P=(2*a+b)/3; '              }];
                     app.Imagealgo.ImageSource = fullfile(pwd, 'photos', 'imarchi.png');
                case 'Brent-Salamin'
                    app.TextAreacode.Value=[...
        {'digits(d)'                }
        {'a = vpa(1,d);'                   }
        {'b = 1/sqrt(vpa(2,d));'             }
        {'s = 1/vpa(4,d);'                  }
        {'p = 1;'                           }
        {'n = ceil(log2(d));'                }
        {'for k = 1:n'                      }
        {'   c = (a+b)/2;'                  }
        {'   b = sqrt(a*b);'                }
        {'   s = s - p*(c-a)^2;'             }
        {'   p = 2*p;'                      }
        {'   a = c;'                        }
        {'end'                                }
        {'P = a^2/s;'                         }];
        app.Imagealgo.ImageSource = fullfile(pwd, 'photos', 'imagm.png');
                case 'Chudnovsky'
                    app.TextAreacode.Value=[{'k = sym(0);'                }
    {'s = sym(0);'                   }
    {'sig = sym(1);'                 }
    {'n = ceil(d/14);'               }
    {'for j = 1:n'                   }
    {'   s = s + sig * prod(3*k+1:6*k)/prod(1:k)^3 * ...'}
    {'       (13591409+545140134*k) / 640320^(3*k+3/2);'}
    {'   k = k+1;'                    }
    {'   sig = -sig;'                   }
    {'end'                            }
    {'S = 1/(12*s);'                    }
    {'P = vpa(S,d);'                   }];
                app.Imagealgo.ImageSource = fullfile(pwd, 'photos', 'imchud.png');
                otherwise
                    disp('error');
             end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MethodstoCalculatePiUIFigure = uifigure('Visible', 'off');
            app.MethodstoCalculatePiUIFigure.Name = 'Methods to Calculate Pi';
            app.GridLayout = uigridlayout(app.MethodstoCalculatePiUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.NumberofDecimalPlacesEditFieldLabel = uilabel(app.GridLayout3);
            app.NumberofDecimalPlacesEditFieldLabel.Text = 'Number of Decimal Places';
            app.NumberofDecimalPlacesEditField = uieditfield(app.GridLayout3, 'numeric');
            app.NumberofDecimalPlacesEditField.Limits = [5 100000];
            app.NumberofDecimalPlacesEditField.Value = 5;
            app.Image = uiimage(app.GridLayout3);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'photos', 'Pi.png');
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.TempsdexcutionensecLabel = uilabel(app.GridLayout5);
            app.TempsdexcutionensecLabel.Text = {'Calculation time '; '(seconds)'};
            app.TempsEditField = uieditfield(app.GridLayout5, 'text');
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.RUNButton = uibutton(app.GridLayout6, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.GridLayout7 = uigridlayout(app.GridLayout);
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.UIAxesH = uiaxes(app.GridLayout8);
            title(app.UIAxesH, 'Digit Histogram')
            app.UIAxesH.XTick = [];
            app.UIAxesH.YTick = [];
            app.UIAxesP = uiaxes(app.GridLayout8);
            title(app.UIAxesP, 'Circular View')
            app.UIAxesP.XTick = [];
            app.UIAxesP.YTick = [];
            app.GridLayout9 = uigridlayout(app.GridLayout7);
            app.TextArea = uitextarea(app.GridLayout9);
            app.GridLayout10 = uigridlayout(app.GridLayout);
            app.MATLABCodeLabel = uilabel(app.GridLayout10);
            app.MATLABCodeLabel.Text = 'MATLAB Code';
            app.GridLayout11 = uigridlayout(app.GridLayout10);
            app.TextArea_2Label = uilabel(app.GridLayout11);
            app.TextArea_2Label.Text = 'Text Area';
            app.TextAreacode = uitextarea(app.GridLayout11);
            app.TextAreacode.Value = {'digits(d);'; 'a = vpa(2*sqrt(2),d);'; 'b = vpa(4,d);'; 'c= (a+b)/2;'; 'for k=3:d'; '        b = b * a / c ;'; '        a = sqrt (b * a ) ;'; '        c = ( a + b ) / 2;'; 'end'; 'P=(2*a+b)/3; '};
            app.Imagealgo = uiimage(app.GridLayout10);
            app.Imagealgo.ImageSource = fullfile(pathToMLAPP, 'photos', 'imarchi.png');
            app.CalculationMethodDropDownLabel = uilabel(app.MethodstoCalculatePiUIFigure);
            app.CalculationMethodDropDownLabel.Text = 'Calculation Method';
            app.CalculationMethodDropDown = uidropdown(app.MethodstoCalculatePiUIFigure);
            app.CalculationMethodDropDown.Items = {'Archimedes', 'Brent-Salamin', 'Chudnovsky'};
            app.CalculationMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @CalculationMethodDropDownValueChanged, true);
            app.CalculationMethodDropDown.Value = 'Archimedes';
            % Show the figure after all components are created
            app.MethodstoCalculatePiUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MethodstoCalculatePi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MethodstoCalculatePiUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MethodstoCalculatePiUIFigure)
        end
    end
end