classdef appPISearch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PiSearchUIFigure  matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        LeftColumn        matlab.ui.container.GridLayout
        ResultsPanel      matlab.ui.container.Panel
        Results           matlab.ui.container.GridLayout
        Rank              matlab.ui.control.Label
        NextButton        matlab.ui.control.Button
        PreviousButton    matlab.ui.control.Button
        AfterO            matlab.ui.control.Label
        O                 matlab.ui.control.Label
        BeforeO           matlab.ui.control.Label
        Occurences        matlab.ui.control.Label
        SearchforPanel    matlab.ui.container.Panel
        SearchGrid        matlab.ui.container.GridLayout
        EditNumber        matlab.ui.control.EditField
        FindinPiButton    matlab.ui.control.Button
        RightColumn       matlab.ui.container.GridLayout
        Flags             matlab.ui.container.GridLayout
        UK                matlab.ui.control.Button
        IT                matlab.ui.control.Button
        FR                matlab.ui.control.Button
        KeyboardPanel     matlab.ui.container.Panel
        Keygrid           matlab.ui.container.GridLayout
        figPi             matlab.ui.control.Image
        Button            matlab.ui.control.Button
        B0                matlab.ui.control.Button
        B9                matlab.ui.control.Button
        B8                matlab.ui.control.Button
        B7                matlab.ui.control.Button
        B4                matlab.ui.control.Button
        B5                matlab.ui.control.Button
        B6                matlab.ui.control.Button
        B3                matlab.ui.control.Button
        B2                matlab.ui.control.Button
        B1                matlab.ui.control.Button
    end

    
    properties (Access = private)
        data % Pi decimals
        mynumber % my digits
        debut % first ocurrence position
        fin % last occurence position
        idx = 1; % index position
        nb % occurence number
        flag = 1; % by default UK
        message % text for all languages
    end
    
    methods (Access = private)
        
        function myflag(app)
          
                    app.SearchforPanel.Title=app.message(1,app.flag+1);
                    app.KeyboardPanel.Title=app.message(8,app.flag+1);
                    app.ResultsPanel.Title=app.message(3,app.flag+1);
                    app.PreviousButton.Text=app.message(6,app.flag+1); 
                    app.NextButton.Text=app.message(7,app.flag+1);
                    app.FindinPiButton.Text=app.message(2,app.flag+1);
                    app.Occurences.Text='';
                    
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(fullfile(pwd, 'photos'), fullfile(pwd, 'pi-search-files'));
            
            app.message=myimportfile('donnees_lang.xlsx');
            myflag(app);
            my_struct=load('digits_results');
            app.data= my_struct.results;
            app.mynumber=app.EditNumber.Value;
          
        end

        % Button pushed function: B1
        function B1ButtonPushed(app, event)
            app.mynumber=[app.mynumber '1'];
            app.EditNumber.Value=app.mynumber;
        end

        % Button pushed function: B2
        function B2ButtonPushed(app, event)
            app.mynumber=[app.mynumber '2'];
            app.EditNumber.Value=app.mynumber;
        end

        % Button pushed function: B3
        function B3ButtonPushed(app, event)
            app.mynumber=[app.mynumber '3'];
            app.EditNumber.Value=app.mynumber;
        end

        % Button pushed function: B4
        function B4ButtonPushed(app, event)
            app.mynumber=[app.mynumber '4'];
            app.EditNumber.Value=app.mynumber;
        end

        % Button pushed function: B5
        function B5ButtonPushed(app, event)
            app.mynumber=[app.mynumber '5'];
            app.EditNumber.Value=app.mynumber;
        end

        % Button pushed function: B6
        function B6ButtonPushed(app, event)
            app.mynumber=[app.mynumber '6'];
            app.EditNumber.Value=app.mynumber;
        end

        % Button pushed function: B7
        function B7ButtonPushed(app, event)
            app.mynumber=[app.mynumber '7'];
            app.EditNumber.Value=app.mynumber;
        end

        % Button pushed function: B8
        function B8ButtonPushed(app, event)
            app.mynumber=[app.mynumber '8'];
            app.EditNumber.Value=app.mynumber;
        end

        % Button pushed function: B9
        function B9ButtonPushed(app, event)
            app.mynumber=[app.mynumber '9'];
            app.EditNumber.Value=app.mynumber;
        end

        % Button pushed function: B0
        function B0ButtonPushed(app, event)
            app.mynumber=[app.mynumber '0'];
            app.EditNumber.Value=app.mynumber;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.mynumber='';
            app.EditNumber.Value=app.mynumber;
            app.Occurences.Text='';
            app.Rank.Text='';
        end

        % Button pushed function: FindinPiButton
        function FindinPiButtonPushed(app, event)
            app.mynumber=app.EditNumber.Value;
            if ~isempty(app.mynumber)
                
                [app.debut,app.fin,app.nb] = find_string_position(app.mynumber);
                % Save in a structure information about result
                app.idx = 1; % Index of result that will be displayed is 1
                if (app.nb==0)
                
                    app.Occurences.Text=app.message(4,app.flag+1);
                    app.O.Text='';
                    app.AfterO.Text='';
                    app.BeforeO.Text='';
                    app.Rank.Text='';
                else
                    app.Occurences.Text=sprintf(app.message(5,app.flag+1),app.nb);
                                   
                    app.O.Text=app.mynumber;
                    app.Rank.Text=sprintf(app.message(9,app.flag+1),app.debut(app.idx));
                    app.BeforeO.Text = ['...' get_string_at_position(max(app.debut(app.idx)-7,0), max(app.debut(app.idx)-1,0))];
                    app.AfterO.Text = [get_string_at_position(min(app.debut(app.idx)+length(app.mynumber)+1,200e6), min(app.debut(app.idx)+length(app.mynumber)+7,200e6)) '...'];
                 end
            end
           
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.nb>app.idx
                app.idx=app.idx+1;
                app.BeforeO.Text = ['...' get_string_at_position(max(app.debut(app.idx)-7,1), max(app.debut(app.idx)-1,1))];
                app.AfterO.Text = [ get_string_at_position(min(app.debut(app.idx)+length(app.mynumber)+1,200e6), min(app.debut(app.idx)+length(app.mynumber)+7,200e6)) '...'];
                app.Rank.Text=sprintf(app.message(9,app.flag+1),app.debut(app.idx));
            end
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            if app.idx>1
                app.idx=app.idx-1;
                app.BeforeO.Text = ['...' get_string_at_position(max(app.debut(app.idx)-7,0), max(app.debut(app.idx)-1,0))];
                app.AfterO.Text = [ get_string_at_position(min(app.debut(app.idx)+length(app.mynumber)+1,200e6), min(app.debut(app.idx)+length(app.mynumber)+7,200e6)) '...'];
                app.Rank.Text=sprintf(app.message(9,app.flag+1),app.debut(app.idx));
            end
        end

        % Button pushed function: UK
        function UKButtonPushed(app, event)
            app.flag=1;
            myflag(app);
        end

        % Button pushed function: IT
        function ITButtonPushed(app, event)
            app.flag=2;
            myflag(app);
        end

        % Button pushed function: FR
        function FRButtonPushed(app, event)
            app.flag=3;
            myflag(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PiSearchUIFigure = uifigure('Visible', 'off');
            app.PiSearchUIFigure.Name = 'Pi Search';
            app.GridLayout = uigridlayout(app.PiSearchUIFigure);
            app.RightColumn = uigridlayout(app.GridLayout);
            app.KeyboardPanel = uipanel(app.RightColumn);
            app.KeyboardPanel.Title = 'Keyboard';
            app.Keygrid = uigridlayout(app.KeyboardPanel);
            app.B1 = uibutton(app.Keygrid, 'push');
            app.B1.ButtonPushedFcn = createCallbackFcn(app, @B1ButtonPushed, true);
            app.B1.Text = '1';
            app.B2 = uibutton(app.Keygrid, 'push');
            app.B2.ButtonPushedFcn = createCallbackFcn(app, @B2ButtonPushed, true);
            app.B2.Text = '2';
            app.B3 = uibutton(app.Keygrid, 'push');
            app.B3.ButtonPushedFcn = createCallbackFcn(app, @B3ButtonPushed, true);
            app.B3.Text = '3';
            app.B6 = uibutton(app.Keygrid, 'push');
            app.B6.ButtonPushedFcn = createCallbackFcn(app, @B6ButtonPushed, true);
            app.B6.Text = '6';
            app.B5 = uibutton(app.Keygrid, 'push');
            app.B5.ButtonPushedFcn = createCallbackFcn(app, @B5ButtonPushed, true);
            app.B5.Text = '5';
            app.B4 = uibutton(app.Keygrid, 'push');
            app.B4.ButtonPushedFcn = createCallbackFcn(app, @B4ButtonPushed, true);
            app.B4.Text = '4';
            app.B7 = uibutton(app.Keygrid, 'push');
            app.B7.ButtonPushedFcn = createCallbackFcn(app, @B7ButtonPushed, true);
            app.B7.Text = '7';
            app.B8 = uibutton(app.Keygrid, 'push');
            app.B8.ButtonPushedFcn = createCallbackFcn(app, @B8ButtonPushed, true);
            app.B8.Text = '8';
            app.B9 = uibutton(app.Keygrid, 'push');
            app.B9.ButtonPushedFcn = createCallbackFcn(app, @B9ButtonPushed, true);
            app.B9.Text = '9';
            app.B0 = uibutton(app.Keygrid, 'push');
            app.B0.ButtonPushedFcn = createCallbackFcn(app, @B0ButtonPushed, true);
            app.B0.Text = '0';
            app.Button = uibutton(app.Keygrid, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'photos', 'PbReturn_image.png');
            app.Button.Text = '';
            app.figPi = uiimage(app.Keygrid);
            app.figPi.ImageSource = fullfile(pathToMLAPP, 'photos', 'Pi.png');
            app.Flags = uigridlayout(app.RightColumn);
            app.FR = uibutton(app.Flags, 'push');
            app.FR.ButtonPushedFcn = createCallbackFcn(app, @FRButtonPushed, true);
            app.FR.Icon = fullfile(pathToMLAPP, 'photos', 'drap_france.png');
            app.FR.Text = '';
            app.IT = uibutton(app.Flags, 'push');
            app.IT.ButtonPushedFcn = createCallbackFcn(app, @ITButtonPushed, true);
            app.IT.Icon = fullfile(pathToMLAPP, 'photos', 'drap_italy.png');
            app.IT.Text = '';
            app.UK = uibutton(app.Flags, 'push');
            app.UK.ButtonPushedFcn = createCallbackFcn(app, @UKButtonPushed, true);
            app.UK.Icon = fullfile(pathToMLAPP, 'photos', 'drap_uk.png');
            app.UK.Text = '';
            app.LeftColumn = uigridlayout(app.GridLayout);
            app.SearchforPanel = uipanel(app.LeftColumn);
            app.SearchforPanel.Title = 'Search for';
            app.SearchGrid = uigridlayout(app.SearchforPanel);
            app.FindinPiButton = uibutton(app.SearchGrid, 'push');
            app.FindinPiButton.ButtonPushedFcn = createCallbackFcn(app, @FindinPiButtonPushed, true);
            app.FindinPiButton.Text = 'Find in Pi';
            app.EditNumber = uieditfield(app.SearchGrid, 'text');
            app.ResultsPanel = uipanel(app.LeftColumn);
            app.ResultsPanel.Title = 'Results';
            app.Results = uigridlayout(app.ResultsPanel);
            app.Occurences = uilabel(app.Results);
            app.Occurences.Text = '';
            app.BeforeO = uilabel(app.Results);
            app.BeforeO.Text = '... 11185546';
            app.O = uilabel(app.Results);
            app.O.Text = '02041984';
            app.AfterO = uilabel(app.Results);
            app.AfterO.Text = '92687023 ...';
            app.PreviousButton = uibutton(app.Results, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = '❮ Previous';
            app.NextButton = uibutton(app.Results, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next ❯';
            app.Rank = uilabel(app.Results);
            app.Rank.Text = '';
            % Show the figure after all components are created
            app.PiSearchUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appPISearch

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PiSearchUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PiSearchUIFigure)
        end
    end
end